classdef unified_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        RecordTab                 matlab.ui.container.Tab
        DButton                   matlab.ui.control.Button
        UButton                   matlab.ui.control.Button
        TIMEDIVKnob               matlab.ui.control.DiscreteKnob
        TIMEDIVKnobLabel          matlab.ui.control.Label
        VOLTSDIVKnob              matlab.ui.control.DiscreteKnob
        VOLTSDIVKnobLabel         matlab.ui.control.Label
        RButton                   matlab.ui.control.Button
        LButton                   matlab.ui.control.Button
        FreqHzEditField           matlab.ui.control.NumericEditField
        FreqHzEditField_2Label    matlab.ui.control.Label
        tmsEditField              matlab.ui.control.NumericEditField
        tmsEditFieldLabel         matlab.ui.control.Label
        AvgVEditField             matlab.ui.control.NumericEditField
        AvgVEditFieldLabel        matlab.ui.control.Label
        PPAmpVEditField           matlab.ui.control.NumericEditField
        PPAmpVEditField_2Label    matlab.ui.control.Label
        MinVEditField_3           matlab.ui.control.NumericEditField
        MinVEditField_3Label      matlab.ui.control.Label
        MaxVEditField             matlab.ui.control.NumericEditField
        MaxVEditField_3Label      matlab.ui.control.Label
        RecordLamp                matlab.ui.control.Lamp
        SAVEButton                matlab.ui.control.Button
        RECORDButton              matlab.ui.control.Button
        AUTOSETButton             matlab.ui.control.Button
        RUNSTOPButton             matlab.ui.control.Button
        RecordPlot                matlab.ui.control.UIAxes
        PlaybackTab               matlab.ui.container.Tab
        LoopSwitch                matlab.ui.control.Switch
        LoopSwitchLabel           matlab.ui.control.Label
        MinVEditField_4           matlab.ui.control.NumericEditField
        MinVEditField_4Label      matlab.ui.control.Label
        AvgVEditField_2           matlab.ui.control.NumericEditField
        AvgVEditField_2Label      matlab.ui.control.Label
        FreqHzEditField_2         matlab.ui.control.NumericEditField
        FreqHzEditField_2Label_2  matlab.ui.control.Label
        PPAmpVEditField_2         matlab.ui.control.NumericEditField
        PPAmpVEditField_2Label_2  matlab.ui.control.Label
        MaxVEditField_2           matlab.ui.control.NumericEditField
        MaxVEditField_2Label      matlab.ui.control.Label
        tmsEditField_2            matlab.ui.control.NumericEditField
        tmsEditField_2Label       matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        TIMEDIVKnob_2             matlab.ui.control.DiscreteKnob
        TIMEDIVKnob_2Label        matlab.ui.control.Label
        VOLTSDIVKnob_2            matlab.ui.control.DiscreteKnob
        VOLTSDIVKnob_2Label       matlab.ui.control.Label
        LButton_2                 matlab.ui.control.Button
        DButton_2                 matlab.ui.control.Button
        UButton_2                 matlab.ui.control.Button
        RButton_2                 matlab.ui.control.Button
        RUNSTOPButton_3           matlab.ui.control.Button
        FileEditField             matlab.ui.control.EditField
        FileEditFieldLabel        matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        ZeroingButton             matlab.ui.control.Button
        EditField                 matlab.ui.control.NumericEditField
        Slider                    matlab.ui.control.Slider
        RecordPlot_2              matlab.ui.control.UIAxes
        PortsDropDown             matlab.ui.control.DropDown
        PortsDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        device % The Seeeduino device

        default_port_message='Select a port'
        

        dispVoltBuffer = [] % Buffer for storing data values
        dispTimestampBuffer = [] % Buffer for storing timestamp values

        saveVoltBuffer = []
        saveTimestampBuffer=[]
        
        
        dataTimer % Timer for data acquisition
        plotTimer % Timer for updating the plot
        showLength = 1000 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot

        % RECORD Btn
        isRecording logical = false
        
        % Click management on the plot
        record_plot_clicked logical = false % To track the number of clicks
        vLine1 % To store the handle to the first vertical line
        vLine2 % To store the handle to the second vertical line
        shadedArea % To store the handle to the shaded area patch

        % Zoom/Shift on the plot
        tTickSize
        vTickSize

        tOffsetByTick
        vOffsetByTick
        
        tTickNumDisp
        vTickNumDisp


    end
    
    methods (Access = private)
        
        function acquireData(app)
            if ~isempty(app.device)
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ',');
                    if length(dataParts) ~= 2
                        error('dataline is wrong, not [timestamps, volts]')
                    end

                    millis = str2double(dataParts{1});
                    numericValue = str2double(dataParts{2});
                    if ~isnan(numericValue) && ~isnan(millis)
                        elapsedMillisec = millis;
                        if app.isRunning
                            app.dispVoltBuffer = [app.dispVoltBuffer, numericValue];
                            app.dispTimestampBuffer = [app.dispTimestampBuffer, elapsedMillisec];
                        end

                        if app.isRecording
                            app.saveVoltBuffer=[app.saveVoltBuffer numericValue];
                            app.saveTimestampBuffer=[app.saveTimestampBuffer elapsedMillisec];
                        end
                        
                        if length(app.dispVoltBuffer) > app.showLength
                            app.dispVoltBuffer = app.dispVoltBuffer(end-app.showLength+1:end);
                            app.dispTimestampBuffer = app.dispTimestampBuffer(end-app.showLength+1:end);
                        end
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning && ~isempty(app.dispTimestampBuffer))
                app.plot();

                % Edit the features
                app.tmsEditField.Value=min(app.dispVoltBuffer);
                app.MaxVEditField.Value=max(app.dispVoltBuffer);
                app.PPAmpVEditField.Value=app.MaxVEditField.Value-app.tmsEditField.Value;
                app.AvgVEditField.Value=mean(app.dispVoltBuffer);
                app.FreqHzEditField.Value=app.DomFreq(app.dispTimestampBuffer,app.dispVoltBuffer);
            end
        end

        
        function resetMarkOnRecordPlot(app)
            if isgraphics(app.vLine1, 'line')
                delete(app.vLine1);
            end
            if isgraphics(app.vLine2, 'line')
                delete(app.vLine2);
            end
            if isgraphics(app.shadedArea, 'patch')
                delete(app.shadedArea);
            end
        end
        
        function saveCSV(app, timestamps, volts)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for saving.']);
                
                % Create a table from the timestamps and volts arrays
                T = table(timestamps(:), volts(:), 'VariableNames', {'Timestamps', 'Volts'});
                
                % Write the table to a CSV file
                writetable(T, fullPath);
                disp(['Data saved to ', fullPath]);
            end
        end

        
        function freq = DomFreq(app,timestamps,volts)

            % Ensure timestamps are uniformly spaced
            dt = mean(diff(timestamps)); % Average time interval between samples
            Fs = 1000/dt % Sampling frequency
            
            % Remove the DC component by subtracting the mean
            volts = volts - mean(volts);
            
            % Compute the Fast Fourier Transform (FFT)
            Y = fft(volts);
            
            % Get the number of points
            n = length(Y);
            Y = Y(1:floor(n/2)+1); % Use only the first half of Y (positive frequencies)
            
            % Compute the frequency values corresponding to Y
            f = (0:floor(n/2)) * Fs / n;
            
            % Find the magnitude of the FFT, which represents the amplitude of each frequency component
            P = abs(Y);
            
            % Find the index of the dominant frequency (excluding the DC component)
            [~, maxIdx] = max(P(2:end)); % Start from index 2 to exclude the DC component (0 Hz)
            maxIdx = maxIdx + 1; % Adjust index because we started from the second element
            
            % Get the dominant frequency based on the index
            freq = f(maxIdx);

        end
        
        function plot(app)
            % Plot the data
                plot(app.RecordPlot, app.dispTimestampBuffer, app.dispVoltBuffer);
                
                % Ensure there's data to work with
                if ~isempty(app.dispTimestampBuffer)
                    % Calculate the right limit of the x-axis based on the latest timestamp
                    lastTimestamp = app.dispTimestampBuffer(end);
                    
                    % Calculate the right limit of the x-axis
                    rtlim = lastTimestamp + app.tOffsetByTick * app.tTickSize;
                    
                    % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
                    ltlim = rtlim - app.tTickNumDisp * app.tTickSize;
                    
                    % Set the x-axis limits
                    xlim(app.RecordPlot, [ltlim, rtlim]);


                end
                
                % Update the plot
                drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            availablePorts = serialportlist("available");
            app.PortsDropDown.Items = [app.default_port_message, availablePorts];
            app.PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.dataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.01, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.plotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...
                                  'TimerFcn', @(src, event)updatePlot(app));

            % Plot tick init
            app.tTickSize = str2double(app.TIMEDIVKnob.Value);
            app.vTickSize = str2double(app.VOLTSDIVKnob.Value);
            
            app.tOffsetByTick=0;
            app.vOffsetByTick=0;

            app.tTickNumDisp=10;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            if ~isempty(app.dataTimer)
                stop(app.dataTimer);
                delete(app.dataTimer);
            end
            if ~isempty(app.plotTimer)
                stop(app.plotTimer);
                delete(app.plotTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: PortsDropDown
        function PortsDropDownValueChanged(app, event)

            value = app.PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    % Start timers when a valid port is selected and device is configured
                    start(app.dataTimer);
                    start(app.plotTimer);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                % Stop timers if the default message is selected
                stop(app.dataTimer);
                stop(app.plotTimer);
            end

        end

        % Button down function: RecordPlot
        function RecordPlotButtonDown(app, event)
            % Get the current point clicked by the user
            currentPoint = app.RecordPlot.CurrentPoint;
            xClick = currentPoint(1,1); % X-coordinate of the click
            
            if ~app.isRunning % Can mark only when the plot updating pauses
                if ~app.record_plot_clicked % First click
                    app.resetMarkOnRecordPlot();
                    app.vLine1 = line(app.RecordPlot, [xClick xClick], ...
                                      app.RecordPlot.YLim, 'Color', 'r', 'LineWidth', 2);
                    app.record_plot_clicked = true;
                else % Second Click
                    if isgraphics(app.vLine1, 'line')
                        pastX = app.vLine1.XData(1);
                        if xClick > pastX
                            app.vLine2 = line(app.RecordPlot, [xClick xClick], ...
                                              app.RecordPlot.YLim, 'Color', 'b', 'LineWidth', 2);
                            app.shadedArea = patch(app.RecordPlot, ...
                                                   'XData', [pastX, pastX, xClick, xClick], ...
                                                   'YData', [app.RecordPlot.YLim(1), app.RecordPlot.YLim(2), app.RecordPlot.YLim(2), app.RecordPlot.YLim(1)], ...
                                                   'FaceColor', [0.65, 0.65, 0.65], 'FaceAlpha', 0.3);
                            app.shadedArea.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
                            app.record_plot_clicked = false; % Reset the click state
                        else
                            app.resetMarkOnRecordPlot();
                            app.vLine1 = line(app.RecordPlot, [xClick xClick], ...
                                          app.RecordPlot.YLim, 'Color', 'r', 'LineWidth', 2);
                        end
                    end
                end
            end
        end

        % Button pushed function: RUNSTOPButton
        function RUNSTOPButtonPushed(app, event)
            if app.isRunning % Stops updating plot
                app.resetMarkOnRecordPlot();
            end
            app.isRunning=~app.isRunning;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)

            if isgraphics(app.vLine1, 'line') && isgraphics(app.vLine2, 'line')
                % Extract the X coordinates of the two lines
                x1 = app.vLine1.XData(1)
                x2 = app.vLine2.XData(1)
                app.dispTimestampBuffer
        
                % Ensure x1 is the smaller value
                if x1 > x2
                    [x1, x2] = deal(x2, x1); % Swap x1 and x2
                end
        
                % Find the indices of the data points that fall within the range [x1, x2]
                inRangeIndices = find(app.dispTimestampBuffer >= x1 & app.dispTimestampBuffer <= x2);
        
                if ~isempty(inRangeIndices)
                    % Extract the data points that fall within the range
                    filteredTimestamps = app.dispTimestampBuffer(inRangeIndices);
                    filteredVolts = app.dispVoltBuffer(inRangeIndices);
        
                    % Call saveCSV to save the filtered data
                    app.saveCSV(filteredTimestamps, filteredVolts);
                else
                    % Handle the case where no data falls within the selected range
                    uialert(app.UIFigure, 'No data falls within the selected range.', 'Save Error');
                end
            else
                % Handle the case where one or both lines have not been placed
                uialert(app.UIFigure, 'Please select a range by placing both lines on the plot.', 'Save Error');
            end

        end

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(app, event)
            if app.isRecording % Stop recording and save the data
                app.saveCSV(app.saveTimestampBuffer, app.saveVoltBuffer);
                app.RecordLamp.Color=[0.80,0.80,0.80];
            else % Start recording
                app.RecordLamp.Color='red';
      
            end
            app.isRecording=~app.isRecording;
        end

        % Value changed function: VOLTSDIVKnob
        function VOLTSDIVKnobValueChanged(app, event)
            app.vTickSize = str2double(app.VOLTSDIVKnob.Value);
            app.plot();
        end

        % Value changed function: TIMEDIVKnob
        function TIMEDIVKnobValueChanged(app, event)
            app.tTickSize = str2double(app.TIMEDIVKnob.Value);
            app.plot();
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            app.tOffsetByTick=app.tOffsetByTick-1;
            app.plot();
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.tOffsetByTick=app.tOffsetByTick+1;
            app.plot();
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            app.vOffsetByTick=app.vOffsetByTick+1;
            app.plot();
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.vOffsetByTick=app.vOffsetByTick-1;
            app.plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PortsDropDownLabel = uilabel(app.UIFigure);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.UIFigure);
            app.PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @PortsDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.RecordPlot = uiaxes(app.RecordTab);
            zlabel(app.RecordPlot, 'Z')
            app.RecordPlot.XTickLabel = '';
            app.RecordPlot.YTickLabel = '';
            app.RecordPlot.ButtonDownFcn = createCallbackFcn(app, @RecordPlotButtonDown, true);
            app.RUNSTOPButton = uibutton(app.RecordTab, 'push');
            app.RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSTOPButtonPushed, true);
            app.RUNSTOPButton.Text = 'RUN/STOP';
            app.AUTOSETButton = uibutton(app.RecordTab, 'push');
            app.AUTOSETButton.Text = 'AUTOSET';
            app.RECORDButton = uibutton(app.RecordTab, 'push');
            app.RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.SAVEButton = uibutton(app.RecordTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.RecordLamp = uilamp(app.RecordTab);
            app.MaxVEditField_3Label = uilabel(app.RecordTab);
            app.MaxVEditField_3Label.Text = 'Max [V]';
            app.MaxVEditField = uieditfield(app.RecordTab, 'numeric');
            app.MinVEditField_3Label = uilabel(app.RecordTab);
            app.MinVEditField_3Label.Text = 'Min [V]';
            app.MinVEditField_3 = uieditfield(app.RecordTab, 'numeric');
            app.PPAmpVEditField_2Label = uilabel(app.RecordTab);
            app.PPAmpVEditField_2Label.Text = 'P-P Amp [V]';
            app.PPAmpVEditField = uieditfield(app.RecordTab, 'numeric');
            app.AvgVEditFieldLabel = uilabel(app.RecordTab);
            app.AvgVEditFieldLabel.Text = 'Avg [V]';
            app.AvgVEditField = uieditfield(app.RecordTab, 'numeric');
            app.tmsEditFieldLabel = uilabel(app.RecordTab);
            app.tmsEditFieldLabel.Text = 'Δt [ms]';
            app.tmsEditField = uieditfield(app.RecordTab, 'numeric');
            app.FreqHzEditField_2Label = uilabel(app.RecordTab);
            app.FreqHzEditField_2Label.Text = 'Freq [Hz]';
            app.FreqHzEditField = uieditfield(app.RecordTab, 'numeric');
            app.LButton = uibutton(app.RecordTab, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.RButton = uibutton(app.RecordTab, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.VOLTSDIVKnobLabel = uilabel(app.RecordTab);
            app.VOLTSDIVKnobLabel.Text = 'VOLTS/DIV';
            app.VOLTSDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.VOLTSDIVKnob.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.VOLTSDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.VOLTSDIVKnob.ValueChangedFcn = createCallbackFcn(app, @VOLTSDIVKnobValueChanged, true);
            app.VOLTSDIVKnob.Value = '200';
            app.TIMEDIVKnobLabel = uilabel(app.RecordTab);
            app.TIMEDIVKnobLabel.Text = 'TIME/DIV';
            app.TIMEDIVKnob = uiknob(app.RecordTab, 'discrete');
            app.TIMEDIVKnob.Items = {'10ms', '50ms', '100ms', '200ms', '500ms', '1s', '2s'};
            app.TIMEDIVKnob.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.TIMEDIVKnob.ValueChangedFcn = createCallbackFcn(app, @TIMEDIVKnobValueChanged, true);
            app.TIMEDIVKnob.Value = '10';
            app.UButton = uibutton(app.RecordTab, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.DButton = uibutton(app.RecordTab, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.RecordPlot_2 = uiaxes(app.PlaybackTab);
            zlabel(app.RecordPlot_2, 'Z')
            app.RecordPlot_2.XTickLabel = '';
            app.RecordPlot_2.YTickLabel = '';
            app.Slider = uislider(app.PlaybackTab);
            app.Slider.Limits = [0 1];
            app.Slider.Value = 0.88;
            app.EditField = uieditfield(app.PlaybackTab, 'numeric');
            app.ZeroingButton = uibutton(app.PlaybackTab, 'push');
            app.ZeroingButton.Text = 'Zeroing';
            app.Lamp = uilamp(app.PlaybackTab);
            app.FileEditFieldLabel = uilabel(app.PlaybackTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.PlaybackTab, 'text');
            app.RUNSTOPButton_3 = uibutton(app.PlaybackTab, 'push');
            app.RUNSTOPButton_3.Text = 'RUN/STOP';
            app.RButton_2 = uibutton(app.PlaybackTab, 'push');
            app.RButton_2.Text = 'R';
            app.UButton_2 = uibutton(app.PlaybackTab, 'push');
            app.UButton_2.Text = 'U';
            app.DButton_2 = uibutton(app.PlaybackTab, 'push');
            app.DButton_2.Text = 'D';
            app.LButton_2 = uibutton(app.PlaybackTab, 'push');
            app.LButton_2.Text = 'L';
            app.VOLTSDIVKnob_2Label = uilabel(app.PlaybackTab);
            app.VOLTSDIVKnob_2Label.Text = 'VOLTS/DIV';
            app.VOLTSDIVKnob_2 = uiknob(app.PlaybackTab, 'discrete');
            app.VOLTSDIVKnob_2.Items = {'10mV', '50mV', '100mV', '200mV', '500mV', '1V', '2V'};
            app.VOLTSDIVKnob_2.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.VOLTSDIVKnob_2.Value = '10';
            app.TIMEDIVKnob_2Label = uilabel(app.PlaybackTab);
            app.TIMEDIVKnob_2Label.Text = 'TIME/DIV';
            app.TIMEDIVKnob_2 = uiknob(app.PlaybackTab, 'discrete');
            app.TIMEDIVKnob_2.Items = {'10ms', '50ms', '100ms', '200ms', '500ms', '1s', '2s'};
            app.TIMEDIVKnob_2.ItemsData = {'10', '50', '100', '200', '500', '1000', '2000'};
            app.TIMEDIVKnob_2.Value = '10';
            app.SearchButton = uibutton(app.PlaybackTab, 'push');
            app.SearchButton.Text = 'Search';
            app.tmsEditField_2Label = uilabel(app.PlaybackTab);
            app.tmsEditField_2Label.Text = 'Δt [ms]';
            app.tmsEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.MaxVEditField_2Label = uilabel(app.PlaybackTab);
            app.MaxVEditField_2Label.Text = 'Max [V]';
            app.MaxVEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.PPAmpVEditField_2Label_2 = uilabel(app.PlaybackTab);
            app.PPAmpVEditField_2Label_2.Text = 'P-P Amp [V]';
            app.PPAmpVEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.FreqHzEditField_2Label_2 = uilabel(app.PlaybackTab);
            app.FreqHzEditField_2Label_2.Text = 'Freq [Hz]';
            app.FreqHzEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.AvgVEditField_2Label = uilabel(app.PlaybackTab);
            app.AvgVEditField_2Label.Text = 'Avg [V]';
            app.AvgVEditField_2 = uieditfield(app.PlaybackTab, 'numeric');
            app.MinVEditField_4Label = uilabel(app.PlaybackTab);
            app.MinVEditField_4Label.Text = 'Min [V]';
            app.MinVEditField_4 = uieditfield(app.PlaybackTab, 'numeric');
            app.LoopSwitchLabel = uilabel(app.PlaybackTab);
            app.LoopSwitchLabel.Text = 'Loop';
            app.LoopSwitch = uiswitch(app.PlaybackTab, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unified_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end